classdef SignalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneralSignalGenerator          matlab.ui.Figure
        SignalSpecificationsPanel       matlab.ui.container.Panel
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        EnterBreakpointsButton          matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        EndofTimeScaleEditFieldLabel    matlab.ui.control.Label
        EndofTimeScaleEditField         matlab.ui.control.NumericEditField
        StartofTimeScaleEditFieldLabel  matlab.ui.control.Label
        StartofTimeScaleEditField       matlab.ui.control.NumericEditField
        SignalperRegionPanel            matlab.ui.container.Panel
        fromtoLabel                     matlab.ui.control.Label
        ImpulsePanel                    matlab.ui.container.Panel
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        ImpulseAmplitudeEditField       matlab.ui.control.NumericEditField
        DCPanel                         matlab.ui.container.Panel
        AmplitudeEditField_2Label       matlab.ui.control.Label
        DCAmplitudeEditField            matlab.ui.control.NumericEditField
        RampPanel                       matlab.ui.container.Panel
        InterceptEditFieldLabel         matlab.ui.control.Label
        InterceptEditField              matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        SignalTypeDropDownLabel         matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        SinusoidalPanel                 matlab.ui.container.Panel
        AmplitudeEditField_4Label       matlab.ui.control.Label
        SinusoidalAmplitudeEditField    matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        DCShiftEditFieldLabel           matlab.ui.control.Label
        SinDCShiftEditField             matlab.ui.control.NumericEditField
        PhaseShiftRadianLabel           matlab.ui.control.Label
        PhaseShiftRadianEditField       matlab.ui.control.NumericEditField
        NextPlotButton                  matlab.ui.control.Button
        ExponentialPanel                matlab.ui.container.Panel
        AmplitudeEditField_3Label       matlab.ui.control.Label
        ExponentialAmplitudeEditField   matlab.ui.control.NumericEditField
        ExponentEditFieldLabel          matlab.ui.control.Label
        ExponentEditField               matlab.ui.control.NumericEditField
        DCShiftEditField_2Label         matlab.ui.control.Label
        ExpDCShiftEditField             matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        BackButton                      matlab.ui.control.Button
        warningLabel                    matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        % GUI related functions
        function hideAll(app)
            % Hiding all the signals' panels
            app.ImpulsePanel.Visible = false;
            app.DCPanel.Visible = false;
            app.RampPanel.Visible = false;
            app.ExponentialPanel.Visible = false;
            app.SinusoidalPanel.Visible = false;
        end
        
        function resetPanel(app)
            % Showing the default signal panel
            hideAll(app);
            app.ImpulsePanel.Visible = true;
            % Default option for the drop down menu
            app.SignalTypeDropDown.Value = "Impulse Signal";
            % Setting the values to all the edit fields to zero
            app.ImpulseAmplitudeEditField.Value = 0;
            app.DCAmplitudeEditField.Value = 0;
            app.SlopeEditField.Value = 0;
            app.InterceptEditField.Value = 0;
            app.ExponentialAmplitudeEditField.Value = 0;
            app.ExponentEditField.Value = 0;
            app.SinusoidalAmplitudeEditField.Value = 0;
            app.FrequencyHzEditField.Value = 0;
            app.PhaseShiftRadianEditField.Value = 0;
            app.SinDCShiftEditField.Value = 0;
        end
        
        function updateFromTo(app)
            % The from to label that determines what time interval
            % is the user entering
            
            % Getting the value required depending on the interval being entered
            timeStart = getTimeInterval(app);
            timeStart = timeStart(end - getCounter(app));
            timeEnd = getTimeInterval(app);
            timeEnd = timeEnd(end - getCounter(app) + 1);
            % Updating the label
            app.fromtoLabel.Text = "From "+num2str(timeStart)+" to "+num2str(timeEnd);
        end
        
        % Saving the inputs of the signal for each time interval
        % and concatenating the small signals to the main signal to be plotted
        function saveInputs(app)
            % Getting the current time interval
            timeStart = getTimeInterval(app);
            timeStart = timeStart(end - getCounter(app)); % start of the current interval
            timeEnd = getTimeInterval(app);
            timeEnd = timeEnd(end - getCounter(app) + 1); % end of the current interval
            n = (timeEnd - timeStart) * getSamplingFrequency(app); % number of samples depending on the sampling frequency
            tVal = linspace(timeStart, timeEnd, n); % time variable for the current interval
            
            % Switch case on the signal entered to set its variables
            signalType = app.SignalTypeDropDown.Value;
            concatTime(app, tVal);
            switch(signalType)
                case "Impulse Signal"
                    setAmplitude(app, app.ImpulseAmplitudeEditField.Value);
                    % Impulse signal is all zeros except at the breakpoint
                    signal = zeros(1, length(tVal));
                    signal(1) = getAmplitude(app);
                    % Concatenating the signal to the main signal
                    concatSignal(app, signal);
                case "DC Signal"
                    setAmplitude(app, app.DCAmplitudeEditField.Value);
                    % DC signal is of constant amplitude
                    signal = getAmplitude(app)*ones(1, length(tVal));
                    concatSignal(app, signal);
                case "Ramp Signal"
                    setSlope(app, app.SlopeEditField.Value);
                    setIntercept(app, app.InterceptEditField.Value);
                    % Ramp signal = slope * time + intercept (y = mx + c)
                    signal = getSlope(app) * tVal + getIntercept(app);
                    concatSignal(app, signal);
                case "Exponential Signal"
                    setAmplitude(app, app.ExponentialAmplitudeEditField.Value);
                    setExponent(app, app.ExponentEditField.Value);
                    setTheta(app, app.ThetaEditField.Value);
                    setExpDCShift(app, app.ExpDCShiftEditField.Value);
                    % Exponential signal = amplitude * e^(exponent * time + time shift) + DC shift
                    signal = getAmplitude(app) * exp(getExponent(app) * tVal + getTheta(app)) + getExpDCShift(app);
                    concatSignal(app, signal);
                case "Sinusoidal Signal"
                    setAmplitude(app, app.SinusoidalAmplitudeEditField.Value);
                    setFrequency(app, app.FrequencyHzEditField.Value);
                    setPhaseShift(app, app.PhaseShiftRadianEditField.Value);
                    setSinDCShift(app, app.SinDCShiftEditField.Value);
                    % Sinusoidal signal = amplitude * sin(2 * pi * frequency * time + phase shift) + DC shift
                    signal = getAmplitude(app) * sin(2*pi*getFrequency(app)*tVal + getPhaseShift(app)) + getSinDCShift(app);
                    concatSignal(app, signal);
            end
        end
        
        % Start and end time validation
        function results = validateTime(app)
            start_t = app.StartofTimeScaleEditField.Value;
            end_t = app.EndofTimeScaleEditField.Value;
            if(start_t >= end_t)
                bool = false;
            else
                bool = true;
            end
            results = bool;
        end
        
        % Breakpoints validation
        function results = validateBreakpoints(app, val)
            if(isempty(val))
                results = true;
                return
            end
            start_t = app.StartofTimeScaleEditField.Value;
            end_t = app.EndofTimeScaleEditField.Value;
            if(start_t>val(1) || end_t<val(end))
                bool = false;
            else
                bool = true;
            end
            results = bool;
        end
        
        % Displaying warnings
        function displayWarning(~, msg)
            opts = struct('WindowStyle','modal',...
                'Interpreter','tex');
            waitfor(warndlg(msg,...
                'Warning', opts));
        end
        
        % Global Variables
        % For the functions to see the data entered by the user
        
        % breakpoints
        function setBreakpoints(~, val)
            global breakpoints;
            breakpoints = val;
        end
        function results = getBreakpoints(~)
            global breakpoints;
            results = breakpoints;
        end
        % timeInterval
        function setTimeInterval(~, val)
            global TimeInterval;
            TimeInterval = val;
        end
        function results = getTimeInterval(~)
            global TimeInterval;
            results = TimeInterval;
        end
        % counter
        function setCounter(~, val)
            global counter;
            counter = val;
        end
        function results = getCounter(~)
            global counter;
            results = counter;
        end
        function results = decrementCounter(app)
            setCounter(app, getCounter(app)-1);
            results = getCounter(app);
        end
        % samplingFrequency
        function setSamplingFrequency(~, val)
            global samplingFrequency;
            samplingFrequency = val;
        end
        function results = getSamplingFrequency(~)
            global samplingFrequency;
            results = samplingFrequency;
        end
        % amplitude
        function setAmplitude(~, val)
            global amplitude;
            amplitude = val;
        end
        function results = getAmplitude(~)
            global amplitude;
            results = amplitude;
        end
        % slope
        function setSlope(~, val)
            global slope;
            slope = val;
        end
        function results = getSlope(~)
            global slope;
            results = slope;
        end
        % intercept
        function setIntercept(~, val)
            global intercept;
            intercept = val;
        end
        function results = getIntercept(~)
            global intercept;
            results = intercept;
        end
        % exponent
        function setExponent(~, val)
            global exponent;
            exponent = val;
        end
        function results = getExponent(~)
            global exponent;
            results = exponent;
        end
        % theta
        function setTheta(~, val)
            global theta;
            theta = val;
        end
        function results = getTheta(~)
            global theta;
            results = theta;
        end
        % expDCShift
        function setExpDCShift(~, val)
            global expDCShift;
            expDCShift = val;
        end
        function results = getExpDCShift(~)
            global expDCShift;
            results = expDCShift;
        end
        % phaseShift
        function setPhaseShift(~, val)
            global phaseShift;
            phaseShift = val;
        end
        function results = getPhaseShift(~)
            global phaseShift;
            results = phaseShift;
        end
        % sinDCShift
        function setSinDCShift(~, val)
            global sinDCShift;
            sinDCShift = val;
        end
        function results = getSinDCShift(~)
            global sinDCShift;
            results = sinDCShift;
        end
        % frequency
        function setFrequency(~, val)
            global frequency;
            frequency = val;
        end
        function results = getFrequency(~)
            global frequency;
            results = frequency;
        end
        % time
        function setTime(~, val)
            global time;
            time = val;
        end
        function results = getTime(~)
            global time;
            results = time;
        end
        function concatTime(app, val)
            time = getTime(app);
            setTime(app, [time val]);
        end
        % signal
        function setSignal(~, val)
            global signal;
            signal = val;
        end
        function results = getSignal(~)
            global signal;
            results = signal;
        end
        function concatSignal(app, val)
            signal = getSignal(app);
            setSignal(app, [signal val]);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Cleaning the workspace
            clc;
            clear;
            close all;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Showing the next panel
            app.SignalSpecificationsPanel.Visible = false;
            app.SignalperRegionPanel.Visible = true;
            
            % Saving the values from the main panel
            timeStart = app.StartofTimeScaleEditField.Value;
            timeEnd = app.EndofTimeScaleEditField.Value;
            breakingPoints = getBreakpoints(app);
            
            % Calculating the time interval
            timeInterval = [timeStart breakingPoints timeEnd];
            timeInterval = sort(timeInterval);
            
            % Setting the global variables
            setTimeInterval(app, timeInterval);
            setCounter(app, length(timeInterval)-1);
            setSamplingFrequency(app, app.SamplingFrequencyEditField.Value);
            setTime(app, []);
            setSignal(app, []);
            
            resetPanel(app);
            
            % GUI related
            % To change the next button text to plot on the last signal before plotting
            if(getCounter(app) == 1)
                app.NextPlotButton.Text = "Plot";
            else
                app.NextPlotButton.Text = "Next";
            end
            updateFromTo(app);
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            % Displaying the correct signal panel upon selecting it
            value = app.SignalTypeDropDown.Value;
            hideAll(app);
            switch(value)
                case "Impulse Signal"
                    app.ImpulsePanel.Visible = true;
                case "DC Signal"
                    app.DCPanel.Visible = true;
                case "Ramp Signal"
                    app.RampPanel.Visible = true;
                case "Exponential Signal"
                    app.ExponentialPanel.Visible = true;
                case "Sinusoidal Signal"
                    app.SinusoidalPanel.Visible = true;
            end
        end

        % Button pushed function: EnterBreakpointsButton
        function EnterBreakpointsButtonPushed(app, event)
            % Getting the breakpoints values
            answer = inputdlg('Enter space-separated numbers:',...
                'Breaking Points', [1 50]);
            % Setting focus on the application
            figure(app.GeneralSignalGenerator);
            if(~isempty(answer))
                user_val = str2num(answer{1}); %#ok<*ST2NM>
                user_val = sort(user_val);
                
                % Setting the breakpoints global variable
                bool = validateBreakpoints(app, user_val);
                if(bool)
                    setBreakpoints(app, user_val);
                else
                    displayWarning(app, 'Breakpoints must be inside the time interval entered.');
                end
                
                if((isempty(user_val) && ~isempty(answer{1})) || ~isreal(user_val))
                    setBreakpoints(app, []); % to keep it clean in case imaginary number entered
                    displayWarning(app, 'Please enter numbers only.');
                end
            end
            % Setting focus on the application
            figure(app.GeneralSignalGenerator);
        end

        % Button pushed function: NextPlotButton
        function NextPlotButtonPushed(app, event)
            saveInputs(app);
            counter = getCounter(app);
            if(counter <= 1)
                % Enlarging the Figure 1 form
                fig = figure(1);
                pos = get(fig,'position');
                set(fig,'position',[pos(1:2)/4 pos(3:4)*2]);
                
                setBreakpoints(app, []);
                
                % Getting the values from the global variables
                time = getTime(app);
                signal = getSignal(app);
                
                % Time Domain
                subplot(211) % subplot at the top half of the figure
                % Generate the plot
                plot(time, signal, 'LineWidth', 1)
                title("Time Domain")
                xlabel("Time")
                ylabel("Amplitude")
                axis 'auto x'
                axis 'auto y'
                grid on
                
                % Frequency Domain
                subplot(212) % subplot at the bottom half of the figure
                Fs = getSamplingFrequency(app); % Sampling frequency
                signal = getSignal(app);
                nfft = 1024; % Length of FFT
                % Take fft, padding with zeros
                X = fft(signal,nfft);
                % FFT is symmetric, we need half of it only
                X = X(1:nfft/2);
                % Take the magnitude of fft
                mx = abs(X);
                % Frequency vector
                f = (0:nfft/2-1)*Fs/nfft;
                % Mirror the plot to obtain double sided spectrum
                f = [-fliplr(f) f];
                mx = [fliplr(mx) mx];
                % Generate the plot
                plot(f, mx, 'LineWidth', 1)
                
                % n = linspace(-Fs/2, Fs/2, length(time));
                % y = fftshift(fft(signal/Fs));
                % ymag = abs(y);
                % plot(n, ymag, 'LineWidth', 1);
                title('Frequency Domain')
                xlabel('Frequency (Hz)')
                ylabel('Amplitude')
                axis 'auto signal'
                axis 'auto y'
                grid on
                
                % Closing the app form upon plotting
                delete(app.GeneralSignalGenerator);
            else
                decrementCounter(app);
                updateFromTo(app);
                resetPanel(app);
                if(getCounter(app) == 1)
                    app.NextPlotButton.Text = "Plot";
                else
                    app.NextPlotButton.Text = "Next";
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Displaying the previous panel (main panel)
            app.SignalSpecificationsPanel.Visible = true;
            app.SignalperRegionPanel.Visible = false;
        end

        % Value changed function: EndofTimeScaleEditField, 
        % StartofTimeScaleEditField
        function StartofTimeScaleEditFieldValueChanged(app, event)
            bool = validateTime(app) && validateBreakpoints(app, getBreakpoints(app));
            app.NextButton.Enable = bool;
            app.warningLabel.Visible = ~bool;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneralSignalGenerator = uifigure;
            app.GeneralSignalGenerator.Name = 'General Signal Generator';
            app.SignalSpecificationsPanel = uipanel(app.GeneralSignalGenerator);
            app.SignalSpecificationsPanel.Title = 'Signal Specifications';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.SignalSpecificationsPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.SignalSpecificationsPanel, 'numeric');
            app.SamplingFrequencyEditField.Limits = [0 Inf];
            app.SamplingFrequencyEditField.Value = 100;
            app.EnterBreakpointsButton = uibutton(app.SignalSpecificationsPanel, 'push');
            app.EnterBreakpointsButton.ButtonPushedFcn = createCallbackFcn(app, @EnterBreakpointsButtonPushed, true);
            app.EnterBreakpointsButton.Text = 'Enter Breakpoints';
            app.NextButton = uibutton(app.SignalSpecificationsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.EndofTimeScaleEditFieldLabel = uilabel(app.SignalSpecificationsPanel);
            app.EndofTimeScaleEditFieldLabel.Text = 'End of Time Scale';
            app.EndofTimeScaleEditField = uieditfield(app.SignalSpecificationsPanel, 'numeric');
            app.EndofTimeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @StartofTimeScaleEditFieldValueChanged, true);
            app.EndofTimeScaleEditField.Value = 6;
            app.StartofTimeScaleEditFieldLabel = uilabel(app.SignalSpecificationsPanel);
            app.StartofTimeScaleEditFieldLabel.Text = 'Start of Time Scale';
            app.StartofTimeScaleEditField = uieditfield(app.SignalSpecificationsPanel, 'numeric');
            app.StartofTimeScaleEditField.ValueChangedFcn = createCallbackFcn(app, @StartofTimeScaleEditFieldValueChanged, true);
            app.SignalperRegionPanel = uipanel(app.GeneralSignalGenerator);
            app.SignalperRegionPanel.Title = 'Signal per Region';
            app.SignalperRegionPanel.Visible = 'off';
            app.fromtoLabel = uilabel(app.SignalperRegionPanel);
            app.fromtoLabel.Text = 'from to';
            app.ImpulsePanel = uipanel(app.SignalperRegionPanel);
            app.ImpulsePanel.Title = 'Impulse';
            app.AmplitudeEditFieldLabel = uilabel(app.ImpulsePanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.ImpulseAmplitudeEditField = uieditfield(app.ImpulsePanel, 'numeric');
            app.ImpulseAmplitudeEditField.Limits = [0 Inf];
            app.DCPanel = uipanel(app.SignalperRegionPanel);
            app.DCPanel.Title = 'DC';
            app.DCPanel.Visible = 'off';
            app.AmplitudeEditField_2Label = uilabel(app.DCPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.DCAmplitudeEditField = uieditfield(app.DCPanel, 'numeric');
            app.RampPanel = uipanel(app.SignalperRegionPanel);
            app.RampPanel.Title = 'Ramp';
            app.RampPanel.Visible = 'off';
            app.InterceptEditFieldLabel = uilabel(app.RampPanel);
            app.InterceptEditFieldLabel.Text = 'Intercept';
            app.InterceptEditField = uieditfield(app.RampPanel, 'numeric');
            app.SlopeEditFieldLabel = uilabel(app.RampPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RampPanel, 'numeric');
            app.SignalTypeDropDownLabel = uilabel(app.SignalperRegionPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.SignalperRegionPanel);
            app.SignalTypeDropDown.Items = {'Impulse Signal', 'DC Signal', 'Ramp Signal', 'Exponential Signal', 'Sinusoidal Signal'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Impulse Signal';
            app.SinusoidalPanel = uipanel(app.SignalperRegionPanel);
            app.SinusoidalPanel.Title = 'Sinusoidal';
            app.SinusoidalPanel.Visible = 'off';
            app.AmplitudeEditField_4Label = uilabel(app.SinusoidalPanel);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.SinusoidalAmplitudeEditField = uieditfield(app.SinusoidalPanel, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.SinusoidalPanel);
            app.FrequencyHzEditFieldLabel.Text = {'Frequency'; '(Hz)'};
            app.FrequencyHzEditField = uieditfield(app.SinusoidalPanel, 'numeric');
            app.DCShiftEditFieldLabel = uilabel(app.SinusoidalPanel);
            app.DCShiftEditFieldLabel.Text = 'DC Shift';
            app.SinDCShiftEditField = uieditfield(app.SinusoidalPanel, 'numeric');
            app.PhaseShiftRadianLabel = uilabel(app.SinusoidalPanel);
            app.PhaseShiftRadianLabel.Text = {'Phase Shift'; '(Radian)'};
            app.PhaseShiftRadianEditField = uieditfield(app.SinusoidalPanel, 'numeric');
            app.NextPlotButton = uibutton(app.SignalperRegionPanel, 'push');
            app.NextPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NextPlotButtonPushed, true);
            app.NextPlotButton.Text = 'Next';
            app.ExponentialPanel = uipanel(app.SignalperRegionPanel);
            app.ExponentialPanel.Title = 'Exponential';
            app.ExponentialPanel.Visible = 'off';
            app.AmplitudeEditField_3Label = uilabel(app.ExponentialPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.ExponentialAmplitudeEditField = uieditfield(app.ExponentialPanel, 'numeric');
            app.ExponentEditFieldLabel = uilabel(app.ExponentialPanel);
            app.ExponentEditFieldLabel.Text = 'Exponent';
            app.ExponentEditField = uieditfield(app.ExponentialPanel, 'numeric');
            app.DCShiftEditField_2Label = uilabel(app.ExponentialPanel);
            app.DCShiftEditField_2Label.Text = 'DC Shift';
            app.ExpDCShiftEditField = uieditfield(app.ExponentialPanel, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.ExponentialPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.ExponentialPanel, 'numeric');
            app.BackButton = uibutton(app.SignalperRegionPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.warningLabel = uilabel(app.GeneralSignalGenerator);
            app.warningLabel.Visible = 'off';
            app.warningLabel.Text = 'Warning! Check the time interval then proceed.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SignalGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneralSignalGenerator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneralSignalGenerator)
        end
    end
end